classdef clasificadorSVM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaquinadeVectoresdeSoporteUIFigure  matlab.ui.Figure
        MQUINADEVECTORESDESOPORTELabel  matlab.ui.control.Label
        NMERODEPARTICIONESKSpinnerLabel  matlab.ui.control.Label
        NMERODEPARTICIONESKSpinner      matlab.ui.control.Spinner
        DIVISINDEDATOSPORVALIDACINCRUZADALabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CARGARButton                    matlab.ui.control.Button
        CARGARNUEVOVECTORDECARACTERSTICASPARAENTRENAMIENTOYPRUEBALabel  matlab.ui.control.Label
        OPCINDEKERNELDropDownLabel      matlab.ui.control.Label
        OPCINDEKERNELDropDown           matlab.ui.control.DropDown
        GENERARButton                   matlab.ui.control.Button
        VISUALIZARButton                matlab.ui.control.Button
        TIPODEVECTORButtonGroup         matlab.ui.container.ButtonGroup
        ANLISISDIRECTOButton            matlab.ui.control.RadioButton
        ANLISISWAVELETButton            matlab.ui.control.RadioButton
        Label_2                         matlab.ui.control.Label
        RESULTADOSLabel                 matlab.ui.control.Label
        EXACTITUDLabel                  matlab.ui.control.Label
        SEGMENTOSPARAENTRENAMIENTOLabel  matlab.ui.control.Label
        SEGMENTOSPARAPRUEBALabel        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    properties (Access = public)
        features 
        features_wavelet 
        train_data
        train_label
        test_data
        test_label
        results
        accuracy
        scores
        a
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)

            app.CallingApp = mainapp;
            app.features = app.CallingApp.Matriz_features;
            app.features_wavelet = app.CallingApp.Matriz_features_wavelet;
            app.a =0;
            app.GENERARButton.Enable = 'off';
        end

        % Button pushed function: CARGARButton
        function CARGARButtonPushed(app, event)
            
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar Variable Matlab');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            workspace = load(strcat(path,file),'train_data','test_data','train_label','test_label');
            app.train_data = workspace.train_data;
            app.test_data = workspace.test_data;
            app.train_label = workspace.train_label;
            app.test_label = workspace.test_label;
            % Generar Etiquetas
%              if  rem(length(app.train_data),2)==0 && rem(length(app.test_data),2)==0
%                app.train_label = [zeros(length(app.train_data)/2,1);ones(length(app.train_data)/2,1)];
%                app.test_label = [zeros(length(app.test_data)/2,1);ones(length(app.test_data)/2,1)];
%             else
%                dimensiontrain = (length(app.train_data)+1)/2;
%                dimensiontest = (length(app.test_data)+1)/2;
%                app.train_label = [zeros((length(app.train_data)+1)/2,1);ones(length(dimensiontrain+1:length(app.train_data)),1)];
%                app.test_label = [zeros((length(app.test_data)+1)/2,1);ones(length(dimensiontest+1:length(app.test_data)),1)];
%              end
            app.a=1;
        end

        % Button pushed function: GENERARButton
        function GENERARButtonPushed(app, event)
            %save file
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            % Put in the name of the mat file that the user wants to save.
            % defaultFileName = fullfile(startingFolder, '*.mat')
            [file,path] = uiputfile('Resultados_SVM.mat','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            end
            fullFileName = fullfile(path, file)
            data_entrenamiento=app.train_data;
            data_prueba =app.test_data;
            etiquetas_entrenamiento = app.train_label;
            etiquetas_prueba = app.test_label;
            svm_resultados = app.results;
            save(fullFileName,'data_entrenamiento','data_prueba','etiquetas_entrenamiento','etiquetas_prueba','svm_resultados')
            
            
        end

        % Button pushed function: VISUALIZARButton
        function VISUALIZARButtonPushed(app, event)
            app.GENERARButton.Enable = 'on';
            if app.ANLISISDIRECTOButton.Value && app.a==0    %Generar conjuntos train y test con features directas
                feature_vector = app.features;  
                labels = app.CallingApp.label; 
            elseif app.ANLISISWAVELETButton.Value && app.a==0 %Generar conjuntos train y test con features wavelet
                feature_vector = app.features_wavelet;
                labels = app.CallingApp.labelwave; 
            end
            %validacion cruzada si no se especifico conjunto para entrenar
            %y probar
            if app.a==0
                k = app.NMERODEPARTICIONESKSpinner.Value;
                cvFolds = crossvalind('Kfold', labels, k);   
                cp = classperf(labels);                      %# init performance tracker
                for i = 1:k                                  %# for each fold
                    testIdx = (cvFolds == i);                %# get indices of test instances
                    trainIdx = ~testIdx; 
                    %Obtener conjuntos train y test
                    app.train_data = feature_vector(trainIdx,:);
                    app.train_label = labels(trainIdx,:);
                    app.test_data = feature_vector(testIdx,:);
                    app.test_label = labels(testIdx,:);
                end
            end
            %Entrenar SVM
            kernel = app.OPCINDEKERNELDropDown.Value;
            
           
            model_svm = fitcsvm(app.train_data, app.train_label,'KernelFunction',kernel,'Standardize',true,...
                'BoxConstraint',1,'ClassNames',[0,1]);
            
            [labelPred,scores] =  predict(model_svm, app.test_data);
            app.results.Predicted_labels = labelPred;
            app.results.test_features = scores;
            
            %Graficar
            plotconfusion(app.test_label',labelPred',{'Matriz de Confusión'});
            set(gca,'xticklabel',{'Clase 0' 'Clase 1'})
            set(gca,'yticklabel',{'Clase 0' 'Clase 1' })
            matriz = confusionmat(app.test_label',labelPred');
            s = sum(matriz);
            %TP + TN / TP + TN + FP + FN
            app.results.Accuracy = 100*(matriz(1,1)+matriz(2,2))/(s(1)+s(2));
            app.Label_3.Text=  num2str(app.results.Accuracy)
            app.Label_4.Text = num2str(length(app.train_data));
            app.Label_5.Text = num2str(length(app.test_data));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaquinadeVectoresdeSoporteUIFigure = uifigure('Visible', 'off');
            app.MaquinadeVectoresdeSoporteUIFigure.Name = 'Maquina de Vectores de Soporte';
            app.MQUINADEVECTORESDESOPORTELabel = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.MQUINADEVECTORESDESOPORTELabel.Text = 'MÁQUINA DE VECTORES DE SOPORTE';
            app.NMERODEPARTICIONESKSpinnerLabel = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.NMERODEPARTICIONESKSpinnerLabel.Text = {'NÚMERO DE'; 'PARTICIONES [K]'};
            app.NMERODEPARTICIONESKSpinner = uispinner(app.MaquinadeVectoresdeSoporteUIFigure);
            app.NMERODEPARTICIONESKSpinner.Limits = [2 10];
            app.NMERODEPARTICIONESKSpinner.Value = 2;
            app.DIVISINDEDATOSPORVALIDACINCRUZADALabel = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.DIVISINDEDATOSPORVALIDACINCRUZADALabel.Text = {'DIVISIÓN DE DATOS POR'; 'VALIDACIÓN CRUZADA'};
            app.Label = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.Label.Text = '________________________________________________________________';
            app.CARGARButton = uibutton(app.MaquinadeVectoresdeSoporteUIFigure, 'push');
            app.CARGARButton.ButtonPushedFcn = createCallbackFcn(app, @CARGARButtonPushed, true);
            app.CARGARButton.Text = 'CARGAR';
            app.CARGARNUEVOVECTORDECARACTERSTICASPARAENTRENAMIENTOYPRUEBALabel = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.CARGARNUEVOVECTORDECARACTERSTICASPARAENTRENAMIENTOYPRUEBALabel.Text = {'CARGAR NUEVO VECTOR'; 'DE CARACTERÍSTICAS PARA'; 'ENTRENAMIENTO Y PRUEBA'};
            app.OPCINDEKERNELDropDownLabel = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.OPCINDEKERNELDropDownLabel.Text = {'OPCIÓN'; 'DE KERNEL'};
            app.OPCINDEKERNELDropDown = uidropdown(app.MaquinadeVectoresdeSoporteUIFigure);
            app.OPCINDEKERNELDropDown.Items = {'RBF', 'LINEAR'};
            app.OPCINDEKERNELDropDown.Value = 'RBF';
            app.GENERARButton = uibutton(app.MaquinadeVectoresdeSoporteUIFigure, 'push');
            app.GENERARButton.ButtonPushedFcn = createCallbackFcn(app, @GENERARButtonPushed, true);
            app.GENERARButton.Text = 'GENERAR';
            app.VISUALIZARButton = uibutton(app.MaquinadeVectoresdeSoporteUIFigure, 'push');
            app.VISUALIZARButton.ButtonPushedFcn = createCallbackFcn(app, @VISUALIZARButtonPushed, true);
            app.VISUALIZARButton.Text = 'VISUALIZAR';
            app.TIPODEVECTORButtonGroup = uibuttongroup(app.MaquinadeVectoresdeSoporteUIFigure);
            app.TIPODEVECTORButtonGroup.Title = 'TIPO DE VECTOR';
            app.ANLISISDIRECTOButton = uiradiobutton(app.TIPODEVECTORButtonGroup);
            app.ANLISISDIRECTOButton.Text = 'ANÁLISIS DIRECTO';
            app.ANLISISDIRECTOButton.Value = true;
            app.ANLISISWAVELETButton = uiradiobutton(app.TIPODEVECTORButtonGroup);
            app.ANLISISWAVELETButton.Text = 'ANÁLISIS WAVELET';
            app.Label_2 = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.Label_2.Text = '________________________________________________________________';
            app.RESULTADOSLabel = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.RESULTADOSLabel.Text = 'RESULTADOS';
            app.EXACTITUDLabel = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.EXACTITUDLabel.Text = 'EXACTITUD';
            app.SEGMENTOSPARAENTRENAMIENTOLabel = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.SEGMENTOSPARAENTRENAMIENTOLabel.Text = {'SEGMENTOS PARA'; 'ENTRENAMIENTO'};
            app.SEGMENTOSPARAPRUEBALabel = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.SEGMENTOSPARAPRUEBALabel.Text = {'SEGMENTOS'; 'PARA PRUEBA'};
            app.Label_3 = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.Label_3.Text = '-----------';
            app.Label_4 = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.Label_4.Text = '-----------';
            app.Label_5 = uilabel(app.MaquinadeVectoresdeSoporteUIFigure);
            app.Label_5.Text = '-----------';
            % Show the figure after all components are created
            app.MaquinadeVectoresdeSoporteUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clasificadorSVM(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaquinadeVectoresdeSoporteUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaquinadeVectoresdeSoporteUIFigure)
        end
    end
end