classdef graficarfeatures < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Carac                    matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        VisualizarButton         matlab.ui.control.Button
        TiempoMinEditFieldLabel  matlab.ui.control.Label
        TiempoMinEditField       matlab.ui.control.NumericEditField
        TiempoMaxEditFieldLabel  matlab.ui.control.Label
        TiempoMaxEditField       matlab.ui.control.NumericEditField
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        CallingApp % Description
    end
    properties (Access = public)
        Minvalue % Description
        Maxvalue % Description
        tiempo
        feature
        canales
        zc
        std
        mav
        kurtosis
        freq
        Matriz_ftures
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp =mainapp;
        end

        % Button pushed function: VisualizarButton
        function VisualizarButtonPushed(app, event)
            app.canales = app.CallingApp.c;
            app.feature = app.CallingApp.op;
            data = app.CallingApp.Matriz_features;
            app.tiempo = 1:length(data);
            i=0;
            app.UIAxes.XLim=[0, length(data)];
            app.UIAxes_2.XLim=[0, length(data)];
            app.UIAxes_3.XLim=[0, length(data)];
            app.UIAxes_4.XLim=[0, length(data)];
            i = length(app.canales);
                        
                if sum(app.feature==1)==4
                    plot(app.UIAxes,app.tiempo,data(:,1)')
                    plot(app.UIAxes_2,app.tiempo,data(:,2*i)')
                    plot(app.UIAxes_3,app.tiempo,data(:,3*i)')
                    plot(app.UIAxes_4,app.tiempo,data(:,4*i)')
                elseif sum(app.feature==1)==3
                    if app.feature(1)==0
                      app.UIAxes.cla;
                      plot(app.UIAxes_2,app.tiempo,data(:,1)')
                      plot(app.UIAxes_3,app.tiempo,data(:,2)')
                      plot(app.UIAxes_4,app.tiempo,data(:,3)')
                    elseif app.feature(2)==0
                      plot(app.UIAxes,app.tiempo,data(:,1)')
                      app.UIAxes_2.cla;
                      plot(app.UIAxes_3,app.tiempo,data(:,2)')
                      plot(app.UIAxes_4,app.tiempo,data(:,3)')
                    elseif app.feature(3)==0
                      plot(app.UIAxes,app.tiempo,data(:,1)')
                      plot(app.UIAxes_2,app.tiempo,data(:,2)')
                      app.UIAxes_3.cla;
                      plot(app.UIAxes_4,app.tiempo,data(:,3)')  
                    elseif app.feature(4)==0
                      plot(app.UIAxes,app.tiempo,data(:,1)')
                      plot(app.UIAxes_2,app.tiempo,data(:,2)')
                      plot(app.UIAxes_3,app.tiempo,data(:,3)')
                      app.UIAxes_4.cla;
                    end
                elseif sum(app.feature==1)==2
                    if app.feature(1)==1 
                      plot(app.UIAxes,app.tiempo,data(:,1)')
                      if app.feature(2)==1
                          plot(app.UIAxes_2,app.tiempo,data(:,2)')
                      elseif app.feature(3)==1
                          plot(app.UIAxes_3,app.tiempo,data(:,2)')
                      else
                          plot(app.UIAxes_4,app.tiempo,data(:,2)')
                      end
                    elseif app.feature(1)==0
                      app.UIAxes.cla;
                      if app.feature(2)==1 && app.feature(4)==0
                          plot(app.UIAxes_2,app.tiempo,data(:,1)')
                          plot(app.UIAxes_3,app.tiempo,data(:,2)')
                          app.UIAxes_4.cla;
                      elseif app.feature(2)==0
                          app.UIAxes_2.cla;
                          plot(app.UIAxes_3,app.tiempo,data(:,1)')
                          plot(app.UIAxes_4,app.tiempo,data(:,2)')
                      end
                    end
                elseif sum(app.feature==1)==1
                    if app.feature(1)==1
                      app.zc = data(:,1)';
                      plot(app.UIAxes,app.tiempo,app.zc)
                      app.UIAxes_2.cla;app.UIAxes_3.cla;app.UIAxes_4.cla;
                    elseif app.feature(2)==1
                      app.std =  data(:,1)';
                      plot(app.UIAxes_2,app.tiempo,app.std)
                      app.UIAxes.cla;app.UIAxes_3.cla;app.UIAxes_4.cla;
                    elseif app.feature(3)==1
                      app.mav = data(:,1)';
                      plot(app.UIAxes_3,app.tiempo,app.mav)
                      app.UIAxes_2.cla;app.UIAxes.cla;app.UIAxes_4.cla;
                    elseif app.feature(4)==1
                      app.kurtosis = data(:,1)';
                      plot(app.UIAxes_4,app.tiempo,app.kurtosis)
                      app.UIAxes_2.cla;app.UIAxes_3.cla;app.UIAxes.cla;
                    end
                end 
                
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Carac = uifigure('Visible', 'off');
            app.Carac.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.Carac);
            title(app.UIAxes, 'Cruces por Cero')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.VisualizarButton = uibutton(app.Carac, 'push');
            app.VisualizarButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizarButtonPushed, true);
            app.VisualizarButton.Text = 'Visualizar';
            app.TiempoMinEditFieldLabel = uilabel(app.Carac);
            app.TiempoMinEditFieldLabel.Text = 'Tiempo Min';
            app.TiempoMinEditField = uieditfield(app.Carac, 'numeric');
            app.TiempoMaxEditFieldLabel = uilabel(app.Carac);
            app.TiempoMaxEditFieldLabel.Text = 'Tiempo Max';
            app.TiempoMaxEditField = uieditfield(app.Carac, 'numeric');
            app.TiempoMaxEditField.Value = 10;
            app.UIAxes_2 = uiaxes(app.Carac);
            title(app.UIAxes_2, 'Valor Medio Absoluto')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.Carac);
            title(app.UIAxes_3, 'Desviación Estándar')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.Carac);
            title(app.UIAxes_4, 'Curtosis')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            % Show the figure after all components are created
            app.Carac.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graficarfeatures(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Carac)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Carac)
        end
    end
end