classdef clasificadorRN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        REDNEURONALARTIFICIALLabel      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        RESULTADOSLabel                 matlab.ui.control.Label
        SEGMENTOSPARAENTRENAMIENTOLabel  matlab.ui.control.Label
        SEGMENTOSPARAPRUEBALabel        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        SEGMENTOSPARAENTRENAREditFieldLabel  matlab.ui.control.Label
        SEGMENTOSPARAENTRENAREditField  matlab.ui.control.NumericEditField
        SEGMENTOSPARAPRUEBAEditFieldLabel  matlab.ui.control.Label
        SEGMENTOSPARAPRUEBAEditField    matlab.ui.control.NumericEditField
        ALGORITMODEENTRENAMIENTODropDownLabel  matlab.ui.control.Label
        ALGORITMODEENTRENAMIENTODropDown  matlab.ui.control.DropDown
        CAPASOCULTASDropDownLabel       matlab.ui.control.Label
        CAPASOCULTASDropDown            matlab.ui.control.DropDown
        CARGARButton                    matlab.ui.control.Button
        CARGARNUEVOVECTORDECARACTERSTICASPARAENTRENAMIENTOYPRUEBALabel  matlab.ui.control.Label
        GENERARButton                   matlab.ui.control.Button
        VISUALIZARButton                matlab.ui.control.Button
        TIPODEVECTORButtonGroup         matlab.ui.container.ButtonGroup
        ANLISISDIRECTOButton            matlab.ui.control.RadioButton
        ANLISISWAVELETButton            matlab.ui.control.RadioButton
        SEGMENTOSPARAVALIDAREditFieldLabel  matlab.ui.control.Label
        SEGMENTOSPARAVALIDAREditField   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    properties (Access = public)
        features_vector 
        features_vector_wavelet 
        train_data
        train_label
        test_data
        test_label
        totfeatures
        totlabels
        results
        trainnet
        accuracy
        y
        scores
        a
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.features_vector = app.CallingApp.Matriz_features;
            app.features_vector_wavelet = app.CallingApp.Matriz_features_wavelet;
            app.a =0;
            app.GENERARButton.Enable = 'off';
        end

        % Button pushed function: CARGARButton
        function CARGARButtonPushed(app, event)
            
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar Variable Matlab');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            workspace = load(strcat(path,file),'train_data','test_data','train_label','test_label');
            app.train_data = workspace.train_data;
            app.test_data = workspace.test_data;
            app.train_label = workspace.train_label;
            app.test_label = workspace.test_label;
            % Generar Etiquetas
%              if  rem(length(app.train_data),2)==0 && rem(length(app.test_data),2)==0
%                app.train_label = [zeros(length(app.train_data)/2,1);ones(length(app.train_data)/2,1)];
%                app.test_label = [zeros(length(app.test_data)/2,1);ones(length(app.test_data)/2,1)];
%             else
%                dimensiontrain = (length(app.train_data)+1)/2;
%                dimensiontest = (length(app.test_data)+1)/2;
%                app.train_label = [zeros((length(app.train_data)+1)/2,1);ones(length(dimensiontrain+1:length(app.train_data)),1)];
%                app.test_label = [zeros((length(app.test_data)+1)/2,1);ones(length(dimensiontest+1:length(app.test_data)),1)];
%              end
            app.a=1;
        end

        % Button pushed function: VISUALIZARButton
        function VISUALIZARButtonPushed(app, event)
            if app.ANLISISDIRECTOButton.Value && app.a==0    %Generar conjuntos train y test con features directas
                feature = app.features_vector;  
                labels = app.CallingApp.label; 
            elseif app.ANLISISWAVELETButton.Value && app.a==0 %Generar conjuntos train y test con features wavelet
                feature =app.features_vector_wavelet;
                labels = app.CallingApp.labelwave; 
            end
            
            if app.a==0
                app.totfeatures = feature;
                app.totlabels = labels;
            elseif app.a==1
                app.totfeatures = [app.train_data;app.test_data];
                app.totlabels = [app.train_label;app.test_label];
            end

            hiddenLayerSize = str2double(app.CAPASOCULTASDropDown.Value);
            trainFcn = app.ALGORITMODEENTRENAMIENTODropDown.Value;
            %Creacion de red neuronal
            net = patternnet(hiddenLayerSize, trainFcn);
            % Entrenar la red 
            [net,tr] = train(net,app.totfeatures',app.totlabels');
            % Setup Division of Data for Training, Validation, Testing
            divitrain = (app.SEGMENTOSPARAENTRENAREditField.Value)-5;
            divitest = (app.SEGMENTOSPARAPRUEBAEditField.Value)-5;
            net.divideParam.trainRatio = divitrain/100;
            net.divideParam.valRatio = 10/100;
            net.divideParam.testRatio = divitest/100;
            % Testear la red
            app.y = net(app.totfeatures'); 
            app.Label_3.Text = num2str(length(tr.testInd));
            app.Label_4.Text = num2str(length(tr.trainInd));
            app.trainnet = tr;
            app.GENERARButton.Enable = 'on';
        end

        % Button pushed function: GENERARButton
        function GENERARButtonPushed(app, event)
            %save file
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            % Put in the name of the mat file that the user wants to save.
            % defaultFileName = fullfile(startingFolder, '*.mat')
            [file,path] = uiputfile('Resultados_RN.mat','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            end
            fullFileName = fullfile(path, file)
            features=app.totfeatures;
            labels=app.totlabels;
            RN_results.net = app.trainnet;
            RN_results.salidas = app.y;
            save(fullFileName,'features','labels','RN_results')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.REDNEURONALARTIFICIALLabel = uilabel(app.UIFigure);
            app.REDNEURONALARTIFICIALLabel.Text = 'RED NEURONAL ARTIFICIAL';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '________________________________________________________________';
            app.RESULTADOSLabel = uilabel(app.UIFigure);
            app.RESULTADOSLabel.Text = 'RESULTADOS';
            app.SEGMENTOSPARAENTRENAMIENTOLabel = uilabel(app.UIFigure);
            app.SEGMENTOSPARAENTRENAMIENTOLabel.Text = {'SEGMENTOS PARA'; 'ENTRENAMIENTO'};
            app.SEGMENTOSPARAPRUEBALabel = uilabel(app.UIFigure);
            app.SEGMENTOSPARAPRUEBALabel.Text = {'SEGMENTOS'; 'PARA PRUEBA'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '-----------';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '-----------';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '________________________________________________________________';
            app.SEGMENTOSPARAENTRENAREditFieldLabel = uilabel(app.UIFigure);
            app.SEGMENTOSPARAENTRENAREditFieldLabel.Text = {'% SEGMENTOS'; 'PARA ENTRENAR'};
            app.SEGMENTOSPARAENTRENAREditField = uieditfield(app.UIFigure, 'numeric');
            app.SEGMENTOSPARAENTRENAREditField.Limits = [0 90];
            app.SEGMENTOSPARAENTRENAREditField.Value = 70;
            app.SEGMENTOSPARAPRUEBAEditFieldLabel = uilabel(app.UIFigure);
            app.SEGMENTOSPARAPRUEBAEditFieldLabel.Text = {'% SEGMENTOS'; 'PARA PRUEBA'};
            app.SEGMENTOSPARAPRUEBAEditField = uieditfield(app.UIFigure, 'numeric');
            app.SEGMENTOSPARAPRUEBAEditField.Limits = [0 50];
            app.SEGMENTOSPARAPRUEBAEditField.Value = 15;
            app.ALGORITMODEENTRENAMIENTODropDownLabel = uilabel(app.UIFigure);
            app.ALGORITMODEENTRENAMIENTODropDownLabel.Text = {'ALGORITMO DE '; 'ENTRENAMIENTO'};
            app.ALGORITMODEENTRENAMIENTODropDown = uidropdown(app.UIFigure);
            app.ALGORITMODEENTRENAMIENTODropDown.Items = {'trainscg', 'trainr', 'trainrp', 'traingd', '', '', ''};
            app.ALGORITMODEENTRENAMIENTODropDown.Value = 'trainscg';
            app.CAPASOCULTASDropDownLabel = uilabel(app.UIFigure);
            app.CAPASOCULTASDropDownLabel.Text = {'CAPAS '; 'OCULTAS'};
            app.CAPASOCULTASDropDown = uidropdown(app.UIFigure);
            app.CAPASOCULTASDropDown.Items = {'8', '10', '12', '15', '', '', ''};
            app.CAPASOCULTASDropDown.Value = '8';
            app.CARGARButton = uibutton(app.UIFigure, 'push');
            app.CARGARButton.ButtonPushedFcn = createCallbackFcn(app, @CARGARButtonPushed, true);
            app.CARGARButton.Text = 'CARGAR';
            app.CARGARNUEVOVECTORDECARACTERSTICASPARAENTRENAMIENTOYPRUEBALabel = uilabel(app.UIFigure);
            app.CARGARNUEVOVECTORDECARACTERSTICASPARAENTRENAMIENTOYPRUEBALabel.Text = {'CARGAR NUEVO VECTOR'; 'DE CARACTERÍSTICAS PARA'; 'ENTRENAMIENTO Y PRUEBA'};
            app.GENERARButton = uibutton(app.UIFigure, 'push');
            app.GENERARButton.ButtonPushedFcn = createCallbackFcn(app, @GENERARButtonPushed, true);
            app.GENERARButton.Text = 'GENERAR';
            app.VISUALIZARButton = uibutton(app.UIFigure, 'push');
            app.VISUALIZARButton.ButtonPushedFcn = createCallbackFcn(app, @VISUALIZARButtonPushed, true);
            app.VISUALIZARButton.Text = 'VISUALIZAR';
            app.TIPODEVECTORButtonGroup = uibuttongroup(app.UIFigure);
            app.TIPODEVECTORButtonGroup.Title = 'TIPO DE VECTOR';
            app.ANLISISDIRECTOButton = uiradiobutton(app.TIPODEVECTORButtonGroup);
            app.ANLISISDIRECTOButton.Text = 'ANÁLISIS DIRECTO';
            app.ANLISISDIRECTOButton.Value = true;
            app.ANLISISWAVELETButton = uiradiobutton(app.TIPODEVECTORButtonGroup);
            app.ANLISISWAVELETButton.Text = 'ANÁLISIS WAVELET';
            app.SEGMENTOSPARAVALIDAREditFieldLabel = uilabel(app.UIFigure);
            app.SEGMENTOSPARAVALIDAREditFieldLabel.Text = {'% SEGMENTOS'; 'PARA VALIDAR'};
            app.SEGMENTOSPARAVALIDAREditField = uieditfield(app.UIFigure, 'numeric');
            app.SEGMENTOSPARAVALIDAREditField.Limits = [0 50];
            app.SEGMENTOSPARAVALIDAREditField.Value = 15;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clasificadorRN(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end