classdef agregarpac < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NuevoPaciente                   matlab.ui.Figure
        SexoButtonGroup                 matlab.ui.container.ButtonGroup
        FButton                         matlab.ui.control.RadioButton
        MButton                         matlab.ui.control.RadioButton
        FechadeNacimientoEditFieldLabel  matlab.ui.control.Label
        FechadeNacimientoEditField      matlab.ui.control.EditField
        AntecedentesTextAreaLabel       matlab.ui.control.Label
        AntecedentesTextArea            matlab.ui.control.TextArea
        personalesLabel                 matlab.ui.control.Label
        patolgicosLabel                 matlab.ui.control.Label
        AadirnuevopacienteLabel         matlab.ui.control.Label
        deLabel                         matlab.ui.control.Label
        preescripcinLabel               matlab.ui.control.Label
        DiagnsticoTextAreaLabel         matlab.ui.control.Label
        DiagnsticoTextArea              matlab.ui.control.TextArea
        CondicindelpacienteButtonGroup  matlab.ui.container.ButtonGroup
        NopadecedeepilepsiaButton       matlab.ui.control.RadioButton
        PadecedeepilepsiaButton         matlab.ui.control.RadioButton
        GuardarButton                   matlab.ui.control.Button
        NuevoButton                     matlab.ui.control.Button
        RegresarButton                  matlab.ui.control.Button
        CdigodepacienteEditFieldLabel   matlab.ui.control.Label
        CdigodepacienteEditField        matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Close request function: NuevoPaciente
        function NuevoPacienteCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
            app.CallingApp.ConsultarButton.Enable = 'on';
            app.CallingApp.RealizarprocesamientoButton.Enable = 'on';
            delete(app)
            
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
            app.CallingApp.ConsultarButton.Enable = 'on';
            app.CallingApp.RealizarprocesamientoButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            id = app.CdigodepacienteEditField.Value;
            sexo = app.SexoButtonGroup.SelectedObject.Text;
            fenac = app.FechadeNacimientoEditField.Value;
            antpat = app.AntecedentesTextArea.Value;
            diagn = app.DiagnsticoTextArea.Value;
            cond = app.CondicindelpacienteButtonGroup.SelectedObject.Text;
            switch cond
                case 'No padece de epilepsia'
                    enf = 'Sano';
                case 'Padece de epilepsia'
                    enf = 'Enfermo';
                otherwise
                    enf = '';
            end
            nuevo={id,sexo,fenac,enf}
            % columnas={'id_paciente','Sexo','Edad','Condicion'};

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NuevoPaciente = uifigure('Visible', 'off');
            app.NuevoPaciente.Name = 'Nuevo Paciente';
            app.NuevoPaciente.CloseRequestFcn = createCallbackFcn(app, @NuevoPacienteCloseRequest, true);
            app.CdigodepacienteEditFieldLabel = uilabel(app.NuevoPaciente);
            app.CdigodepacienteEditFieldLabel.Text = 'Código de paciente';
            app.CdigodepacienteEditField = uieditfield(app.NuevoPaciente, 'text');
            app.SexoButtonGroup = uibuttongroup(app.NuevoPaciente);
            app.SexoButtonGroup.Title = 'Sexo';
            app.FButton = uiradiobutton(app.SexoButtonGroup);
            app.FButton.Text = 'F';
            app.FButton.Value = true;
            app.MButton = uiradiobutton(app.SexoButtonGroup);
            app.MButton.Text = 'M';
            app.FechadeNacimientoEditFieldLabel = uilabel(app.NuevoPaciente);
            app.FechadeNacimientoEditFieldLabel.Text = 'Fecha de Nacimiento';
            app.FechadeNacimientoEditField = uieditfield(app.NuevoPaciente, 'text');
            app.AntecedentesTextAreaLabel = uilabel(app.NuevoPaciente);
            app.AntecedentesTextAreaLabel.Text = 'Antecedentes';
            app.AntecedentesTextArea = uitextarea(app.NuevoPaciente);
            app.personalesLabel = uilabel(app.NuevoPaciente);
            app.personalesLabel.Text = 'personales';
            app.patolgicosLabel = uilabel(app.NuevoPaciente);
            app.patolgicosLabel.Text = 'patológicos';
            app.AadirnuevopacienteLabel = uilabel(app.NuevoPaciente);
            app.AadirnuevopacienteLabel.Text = 'Añadir nuevo paciente';
            app.deLabel = uilabel(app.NuevoPaciente);
            app.deLabel.Text = 'de';
            app.preescripcinLabel = uilabel(app.NuevoPaciente);
            app.preescripcinLabel.Text = 'preescripción';
            app.DiagnsticoTextAreaLabel = uilabel(app.NuevoPaciente);
            app.DiagnsticoTextAreaLabel.Text = 'Diagnóstico';
            app.DiagnsticoTextArea = uitextarea(app.NuevoPaciente);
            app.CondicindelpacienteButtonGroup = uibuttongroup(app.NuevoPaciente);
            app.CondicindelpacienteButtonGroup.Title = 'Condición del paciente';
            app.NopadecedeepilepsiaButton = uiradiobutton(app.CondicindelpacienteButtonGroup);
            app.NopadecedeepilepsiaButton.Text = 'No padece de epilepsia';
            app.NopadecedeepilepsiaButton.Value = true;
            app.PadecedeepilepsiaButton = uiradiobutton(app.CondicindelpacienteButtonGroup);
            app.PadecedeepilepsiaButton.Text = 'Padece de epilepsia';
            app.GuardarButton = uibutton(app.NuevoPaciente, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.NuevoButton = uibutton(app.NuevoPaciente, 'push');
            app.NuevoButton.Text = 'Nuevo';
            app.RegresarButton = uibutton(app.NuevoPaciente, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            % Show the figure after all components are created
            app.NuevoPaciente.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = agregarpac(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NuevoPaciente)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NuevoPaciente)
        end
    end
end