classdef principal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Principal                   matlab.ui.Figure
        AgregarnuevopacienteButton  matlab.ui.control.Button
        ConsultarButton             matlab.ui.control.Button
        AnalizarpruebaButton        matlab.ui.control.Button
        BASEDEDATOSDESEALESBIOMDICASLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        AgregarPaciente
        RealizarConsulta
        AnalizarPrueba
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Habilitar botones
            app.AgregarnuevopacienteButton.Enable = 'on';
            app.ConsultarButton.Enable = 'on';
            app.AnalizarpruebaButton.Enable = 'on';
        end

        % Button pushed function: AgregarnuevopacienteButton
        function AgregarnuevopacienteButtonPushed(app, event)
            % Deshabilitar botones
            app.AgregarnuevopacienteButton.Enable = 'off';
            app.ConsultarButton.Enable = 'off';
            app.AnalizarpruebaButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            con = database('mysql', 'root', 'secret');
            app.AgregarPaciente = agregarpac(app,con);
            
        end

        % Close request function: Principal
        function PrincipalCloseRequest(app, event)
            % Delete apps
            delete(app.AgregarPaciente)
            delete(app.RealizarConsulta)
            delete(app)
            
        end

        % Button pushed function: ConsultarButton
        function ConsultarButtonPushed(app, event)
            % Deshabilitar botones
            app.AgregarnuevopacienteButton.Enable = 'off';
            app.ConsultarButton.Enable = 'off';
            app.AnalizarpruebaButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.RealizarConsulta = consultas(app);
        end

        % Button pushed function: AnalizarpruebaButton
        function AnalizarpruebaButtonPushed(app, event)
            % Deshabilitar botones
            app.AgregarnuevopacienteButton.Enable = 'off';
            app.ConsultarButton.Enable = 'off';
            app.AnalizarpruebaButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.AnalizarPrueba = procesamiento(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Principal = uifigure('Visible', 'off');
            app.Principal.Name = 'Principal';
            app.Principal.CloseRequestFcn = createCallbackFcn(app, @PrincipalCloseRequest, true);
            app.AgregarnuevopacienteButton = uibutton(app.Principal, 'push');
            app.AgregarnuevopacienteButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarnuevopacienteButtonPushed, true);
            app.AgregarnuevopacienteButton.Text = 'Agregar nuevo paciente';
            app.ConsultarButton = uibutton(app.Principal, 'push');
            app.ConsultarButton.ButtonPushedFcn = createCallbackFcn(app, @ConsultarButtonPushed, true);
            app.ConsultarButton.Text = 'Consultar';
            app.AnalizarpruebaButton = uibutton(app.Principal, 'push');
            app.AnalizarpruebaButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizarpruebaButtonPushed, true);
            app.AnalizarpruebaButton.Text = 'Analizar prueba';
            app.BASEDEDATOSDESEALESBIOMDICASLabel = uilabel(app.Principal);
            app.BASEDEDATOSDESEALESBIOMDICASLabel.Text = 'BASE DE DATOS DE SEÑALES BIOMÉDICAS';
            app.Image = uiimage(app.Principal);
            app.Image.ImageSource = 'logouvg.jpg';
            % Show the figure after all components are created
            app.Principal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = principal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Principal)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Principal)
        end
    end
end