classdef procesamiento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Carac                    matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        TiempoMinEditFieldLabel  matlab.ui.control.Label
        TiempoMinEditField       matlab.ui.control.NumericEditField
        TiempoMaxEditFieldLabel  matlab.ui.control.Label
        TiempoMaxEditField       matlab.ui.control.NumericEditField
        SeleccionarButton        matlab.ui.control.Button
        CanalSpinnerLabel        matlab.ui.control.Label
        CanalSpinner             matlab.ui.control.Spinner
        VisualizarButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        Minvalue % Description
        Maxvalue % Description
        tiempo
        datos
        header
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SeleccionarButton
        function SeleccionarButtonPushed(app, event)
            [file,path,indx] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            
            [hdr, record] = edfread(strcat(path,file));
            app.datos = record;
            app.header = hdr;
        end

        % Button pushed function: VisualizarButton
        function VisualizarButtonPushed(app, event)
            value = app.CanalSpinner.Value;
            record = app.datos;
            hdr = app.header;
            freq = hdr.frequency(1);
            app.tiempo = 1:length(record);
            tmin = app.TiempoMinEditField.Value;
            tmax = app.TiempoMaxEditField.Value;
            %             if tmin>freq
            %                 app.UIAxes.XLim=[(tmin*256), (tmax*256)];
            %             elseif tmin<=freq && tmax<freq
            %                 app.UIAxes.XLim=[tmin, tmax];
            %             end
            app.UIAxes.XLim=[(tmin*256), (tmax*256)];
            
            switch value
                case 1
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 2
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 3
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 4
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 5
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 6
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 7
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 8
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 9
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 10
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 11
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 12
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 13
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 14
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 15
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 16
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 17
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 18
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 19
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 20
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 21
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 22
                    plot(app.UIAxes,app.tiempo,record(value,:))
                case 23
                    plot(app.UIAxes,app.tiempo,record(value,:))
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Carac = uifigure('Visible', 'off');
            app.Carac.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.Carac);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SeleccionarButton = uibutton(app.Carac, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarButtonPushed, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.CanalSpinnerLabel = uilabel(app.Carac);
            app.CanalSpinnerLabel.Text = 'Canal';
            app.CanalSpinner = uispinner(app.Carac);
            app.CanalSpinner.Limits = [1 23];
            app.CanalSpinner.Value = 1;
            app.VisualizarButton = uibutton(app.Carac, 'push');
            app.VisualizarButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizarButtonPushed, true);
            app.VisualizarButton.Text = 'Visualizar';
            app.TiempoMinEditFieldLabel = uilabel(app.Carac);
            app.TiempoMinEditFieldLabel.Text = 'Tiempo Min';
            app.TiempoMinEditField = uieditfield(app.Carac, 'numeric');
            app.TiempoMaxEditFieldLabel = uilabel(app.Carac);
            app.TiempoMaxEditFieldLabel.Text = 'Tiempo Max';
            app.TiempoMaxEditField = uieditfield(app.Carac, 'numeric');
            app.TiempoMaxEditField.Value = 10;
            % Show the figure after all components are created
            app.Carac.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = procesamiento

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Carac)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Carac)
        end
    end
end